classdef kostky_ruta < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        Hr2Label                 matlab.ui.control.Label
        Hr1Label                 matlab.ui.control.Label
        checkDice5CheckBox_2     matlab.ui.control.CheckBox
        checkDice4CheckBox_2     matlab.ui.control.CheckBox
        checkDice3CheckBox_2     matlab.ui.control.CheckBox
        checkDice2CheckBox_2     matlab.ui.control.CheckBox
        checkDice1CheckBox_2     matlab.ui.control.CheckBox
        Dice5EditField_2         matlab.ui.control.NumericEditField
        Dice5EditField_2Label    matlab.ui.control.Label
        Dice4EditField_2         matlab.ui.control.NumericEditField
        Dice4EditField_2Label    matlab.ui.control.Label
        Dice3EditField_2         matlab.ui.control.NumericEditField
        Dice3EditField_2Label    matlab.ui.control.Label
        Dice2EditField_2         matlab.ui.control.NumericEditField
        Dice2EditField_2Label    matlab.ui.control.Label
        Dice1EditField_2         matlab.ui.control.NumericEditField
        Dice1EditField_2Label    matlab.ui.control.Label
        checkDice5CheckBox       matlab.ui.control.CheckBox
        checkDice4CheckBox       matlab.ui.control.CheckBox
        checkDice3CheckBox       matlab.ui.control.CheckBox
        checkDice2CheckBox       matlab.ui.control.CheckBox
        checkDice1CheckBox       matlab.ui.control.CheckBox
        Dice1EditField           matlab.ui.control.NumericEditField
        Dice1EditFieldLabel      matlab.ui.control.Label
        Dice2EditField           matlab.ui.control.NumericEditField
        Dice2EditFieldLabel      matlab.ui.control.Label
        EndStatusEditField       matlab.ui.control.EditField
        EndStatusEditFieldLabel  matlab.ui.control.Label
        Dice5EditField           matlab.ui.control.NumericEditField
        Dice5EditFieldLabel      matlab.ui.control.Label
        Dice4EditField           matlab.ui.control.NumericEditField
        Dice4EditFieldLabel      matlab.ui.control.Label
        Dice3EditField           matlab.ui.control.NumericEditField
        Dice3EditFieldLabel      matlab.ui.control.Label
        HoditButton              matlab.ui.control.StateButton
    end

    
    properties (Access = private)
        dice_value = [0 0 0 0 0]
        round = 1;
        dices;
        checks;
        Number_dice = 5;
        dices2;
        checks2;
        dice_value2 = [0 0 0 0 0]
        human = true;
    end
    
    methods (Access = private)
        
        function results = Display(app)
            for i = 1 : app.Number_dice
                app.dices(i).Value = app.dice_value(i);
                app.checks(i).Visible = true;

                app.dices2(i).Value = app.dice_value2(i);
                app.checks2(i).Visible = true;
            end
        end
        
        function results = reset(app)
            app.dice_value = [0 0 0 0 0];
                    app.dice_value2 = [0 0 0 0 0];
                    app.round = 1;
                    for i = 1 : app.Number_dice
                           app.dices(i).Value = app.dice_value(i);
                           app.checks(i).Visible = false;
                           app.checks(i).Value = false;
                           app.dices2(i).Value = app.dice_value2(i);
                           app.checks2(i).Visible = false;
                           app.checks2(i).Value = false;
                    end
                    app.HoditButton.Text="Hodit";
                    app.EndStatusEditField.Visible= false;
        end
        
        function results = endgame(app)
            
            value_counted  = histcounts(app.dice_value,[1 2 3 4 5 6 99 ])
            
            druhy_Index=0;
            [m,index] = max(value_counted)

           if m == 5 %pětice
               body=7;
           elseif m==4 %čtveřice
               body=6;
           elseif m==3 && length(find(value_counted==2))==1 %full house
               body=5;
               druhy_Index = find(value_counted==2);
           elseif m==1 && (value_counted(1)==0||value_counted(6)==0) %%postupka
               body=4;
           elseif m==3 %trojice
               body=3;
           elseif m==2 &&  length(find(value_counted==2))==2 %%dva páry
                body=2;
                cache=find(value_counted==2);
                index = cache(2);
                druhy_Index=cache(1);
           elseif m==2 %%pár
               body=1;
           else
               body=0;
           end
           hrac1=[body index druhy_Index]

           value_counted = histcounts(app.dice_value2,[1 2 3 4 5 6 99 ])
            druhy_Index=0;
            [m,index] = max(value_counted)

          if m == 5 %pětice
               body=7;
           elseif m==4 %čtveřice
               body=6;
           elseif m==3 && length(find(value_counted==2))==1 %full house
               body=5;
               druhy_Index = find(value_counted==2);
           elseif m==1 && (value_counted(1)==0||value_counted(6)==0) %%postupka
               body=4;
           elseif m==3 %trojice
               body=3;
           elseif m==2 &&  length(find(value_counted==2))==2 %%dva páry
                body=2;
                cache=find(value_counted==2);
                index = cache(2);
                druhy_Index=cache(1);
           elseif m==2 %%pár
               body=1;
           else
               body=0;
           end
           hrac2=[body index druhy_Index]

           if hrac2(1) > hrac1(1)
               app.EndStatusEditField.Value="Hráč 2 vyhrál";
           elseif hrac1(1) > hrac2(1)
                app.EndStatusEditField.Value="Hráč 1 vyhrál";
           elseif hrac1(2) > hrac2(2)
                app.EndStatusEditField.Value="Hráč 1 vyhrál";
           elseif hrac2(2) > hrac1(2)
                app.EndStatusEditField.Value="Hráč 2 vyhrál";
           elseif  hrac1(3) > hrac2(3)
                 app.EndStatusEditField.Value="Hráč 1 vyhrál";
            elseif  hrac2(3) > hrac1(3)
                 app.EndStatusEditField.Value="Hráč 2 vyhrál";
           else
               app.EndStatusEditField.Value="Remíza";
           end


        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.dices = [ app.Dice1EditField app.Dice2EditField  app.Dice3EditField app.Dice4EditField app.Dice5EditField];
            app.checks = [app.checkDice1CheckBox app.checkDice2CheckBox app.checkDice3CheckBox app.checkDice4CheckBox app.checkDice5CheckBox ];
            app.dices2 = [app.Dice1EditField_2 app.Dice2EditField_2 app.Dice3EditField_2 app.Dice4EditField_2 app.Dice5EditField_2];
            app.checks2 = [app.checkDice1CheckBox_2 app.checkDice2CheckBox_2 app.checkDice3CheckBox_2 app.checkDice4CheckBox_2 app.checkDice5CheckBox_2];
        end

        % Value changed function: HoditButton
        function HoditButtonValueChanged(app, event)
            value = app.HoditButton.Value;           
            

            if app.human %multiplayer
                if app.round ==1 %%round 1
                    app.dice_value = randi(6,5,1);
                    app.dice_value2 = randi(6,5,1);
                    app.round =2;
                    app.Display();
                elseif app.round ==2
                     for i = 1 : app.Number_dice
                          if app.checks(i).Value
                            app.dice_value(i) = ceil(rand*6);
                          end
                          if app.checks2(i).Value
                            app.dice_value2(i) = ceil(rand*6);
                          end

    
                     end
                     app.Display();
                     %%endgame
                     app.round=3;
                     app.EndStatusEditField.Visible= true;

                     app.endgame();

                     app.HoditButton.Text="Hrát znovu";
    
                elseif app.round == 3 %%reset
                    app.reset();
                end
            end


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.HoditButton = uibutton(app.UIFigure, 'state');
            app.HoditButton.ValueChangedFcn = createCallbackFcn(app, @HoditButtonValueChanged, true);
            app.HoditButton.Text = 'Hodit';
            app.Dice3EditFieldLabel = uilabel(app.UIFigure);
            app.Dice3EditFieldLabel.Text = 'Dice3';
            app.Dice3EditField = uieditfield(app.UIFigure, 'numeric');
            app.Dice3EditField.Editable = 'off';
            app.Dice4EditFieldLabel = uilabel(app.UIFigure);
            app.Dice4EditFieldLabel.Text = 'Dice4';
            app.Dice4EditField = uieditfield(app.UIFigure, 'numeric');
            app.Dice4EditField.Editable = 'off';
            app.Dice5EditFieldLabel = uilabel(app.UIFigure);
            app.Dice5EditFieldLabel.Text = 'Dice5';
            app.Dice5EditField = uieditfield(app.UIFigure, 'numeric');
            app.Dice5EditField.Editable = 'off';
            app.EndStatusEditFieldLabel = uilabel(app.UIFigure);
            app.EndStatusEditFieldLabel.Visible = 'off';
            app.EndStatusEditFieldLabel.Text = 'EndStatus';
            app.EndStatusEditField = uieditfield(app.UIFigure, 'text');
            app.EndStatusEditField.Editable = 'off';
            app.EndStatusEditField.Visible = 'off';
            app.Dice2EditFieldLabel = uilabel(app.UIFigure);
            app.Dice2EditFieldLabel.Text = 'Dice2';
            app.Dice2EditField = uieditfield(app.UIFigure, 'numeric');
            app.Dice2EditField.Editable = 'off';
            app.Dice1EditFieldLabel = uilabel(app.UIFigure);
            app.Dice1EditFieldLabel.Text = 'Dice1';
            app.Dice1EditField = uieditfield(app.UIFigure, 'numeric');
            app.Dice1EditField.Editable = 'off';
            app.checkDice1CheckBox = uicheckbox(app.UIFigure);
            app.checkDice1CheckBox.Visible = 'off';
            app.checkDice1CheckBox.Text = '';
            app.checkDice2CheckBox = uicheckbox(app.UIFigure);
            app.checkDice2CheckBox.Visible = 'off';
            app.checkDice2CheckBox.Text = '';
            app.checkDice3CheckBox = uicheckbox(app.UIFigure);
            app.checkDice3CheckBox.Visible = 'off';
            app.checkDice3CheckBox.Text = '';
            app.checkDice4CheckBox = uicheckbox(app.UIFigure);
            app.checkDice4CheckBox.Visible = 'off';
            app.checkDice4CheckBox.Text = '';
            app.checkDice5CheckBox = uicheckbox(app.UIFigure);
            app.checkDice5CheckBox.Visible = 'off';
            app.checkDice5CheckBox.Text = '';
            app.Dice1EditField_2Label = uilabel(app.UIFigure);
            app.Dice1EditField_2Label.Text = 'Dice1';
            app.Dice1EditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.Dice1EditField_2.Editable = 'off';
            app.Dice2EditField_2Label = uilabel(app.UIFigure);
            app.Dice2EditField_2Label.Text = 'Dice2';
            app.Dice2EditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.Dice2EditField_2.Editable = 'off';
            app.Dice3EditField_2Label = uilabel(app.UIFigure);
            app.Dice3EditField_2Label.Text = 'Dice3';
            app.Dice3EditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.Dice3EditField_2.Editable = 'off';
            app.Dice4EditField_2Label = uilabel(app.UIFigure);
            app.Dice4EditField_2Label.Text = 'Dice4';
            app.Dice4EditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.Dice4EditField_2.Editable = 'off';
            app.Dice5EditField_2Label = uilabel(app.UIFigure);
            app.Dice5EditField_2Label.Text = 'Dice5';
            app.Dice5EditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.Dice5EditField_2.Editable = 'off';
            app.checkDice1CheckBox_2 = uicheckbox(app.UIFigure);
            app.checkDice1CheckBox_2.Visible = 'off';
            app.checkDice1CheckBox_2.Text = '';
            app.checkDice2CheckBox_2 = uicheckbox(app.UIFigure);
            app.checkDice2CheckBox_2.Visible = 'off';
            app.checkDice2CheckBox_2.Text = '';
            app.checkDice3CheckBox_2 = uicheckbox(app.UIFigure);
            app.checkDice3CheckBox_2.Visible = 'off';
            app.checkDice3CheckBox_2.Text = '';
            app.checkDice4CheckBox_2 = uicheckbox(app.UIFigure);
            app.checkDice4CheckBox_2.Visible = 'off';
            app.checkDice4CheckBox_2.Text = '';
            app.checkDice5CheckBox_2 = uicheckbox(app.UIFigure);
            app.checkDice5CheckBox_2.Visible = 'off';
            app.checkDice5CheckBox_2.Text = '';
            app.Hr1Label = uilabel(app.UIFigure);
            app.Hr1Label.Text = 'Hráč 1';
            app.Hr2Label = uilabel(app.UIFigure);
            app.Hr2Label.Text = 'Hráč 2';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = kostky_ruta

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end